classdef App_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Max_In_Bone                     matlab.ui.control.NumericEditField
        MaximumdistanceinbonemmEditFieldLabel  matlab.ui.control.Label
        ParametersofplacedplateLabel    matlab.ui.control.Label
        Parameters_values               matlab.ui.control.Label
        Reset_parameters                matlab.ui.control.Button
        stlLabel                        matlab.ui.control.Label
        Save_Name                       matlab.ui.control.EditField
        Parameters                      matlab.ui.control.Label
        Plate_Bone_Dist                 matlab.ui.control.NumericEditField
        DistanceplatebonetocountassurfacecontactmmLabel  matlab.ui.control.Label
        Min_Dist                        matlab.ui.control.NumericEditField
        MinimaldistanceundertibiaplateaummEditFieldLabel  matlab.ui.control.Label
        FordetailedexplanationandinstructionsseedocumentationLabel  matlab.ui.control.Label
        Left_or_Right_dropdown          matlab.ui.control.DropDown
        LeftorrightkneeLabel            matlab.ui.control.Label
        SWlabel                         matlab.ui.control.Label
        LoadSWButton                    matlab.ui.control.Button
        PlatetransparentSwitch          matlab.ui.control.Switch
        PlatetransparentSwitchLabel     matlab.ui.control.Label
        BonetransparentSwitch           matlab.ui.control.Switch
        BonetransparentSwitchLabel      matlab.ui.control.Label
        platefileLabel                  matlab.ui.control.Label
        MargintobothsidesEditField      matlab.ui.control.NumericEditField
        MargintobothsidesLabel          matlab.ui.control.Label
        Ratio_100_minus_input           matlab.ui.control.Label
        Max_Dist                        matlab.ui.control.NumericEditField
        MaximaldistanceundertibiaplateaummLabel  matlab.ui.control.Label
        Ratio_Field                     matlab.ui.control.NumericEditField
        AnglemaxLabel                   matlab.ui.control.Label
        Angle_Max_field                 matlab.ui.control.NumericEditField
        Angle_Min_field                 matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        bonefileLabel                   matlab.ui.control.Label
        ExportplatepositionButton       matlab.ui.control.Button
        RecalculateplatepositionButton  matlab.ui.control.Button
        RatioofthelocationoftheplatebetweentheMCLandtuberosityLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LoadplateButton                 matlab.ui.control.Button
        LoadboneButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        bone % bone mesh
        bonemesh
        bonemeshtrans
        plate % plate mesh
        platemesh
        platemeshtrans
        surgicalwindow
        SWmesh
        SWmeshtrans
        plate_placed % placed plate mesh
        ratio % ratio
        angle_min % angle min
        angle_max % angle max
        margin
        bone_transparent
        plate_transparent
        sw_or_bone
        L_or_R
        dist_min
        dist_max
        plate_bone_dist
        parameters
        save_name
        platemesh_placed
        platemesh_placedtrans
        surgicalwindow_mirror
        bone_mirror
        plate_placed_mirror
        Points_wedge
        P_wedge
        max_in_bone
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            hold(app.UIAxes, 'on')
            axis(app.UIAxes,"equal")
            camlight(app.UIAxes,'headlight')
            light(app.UIAxes,'Position', [-20, -20, -20])
            view(app.UIAxes,[-25, 30])
            xlim(app.UIAxes,[-50, 50])
            ylim(app.UIAxes,[-50, 50])
            zlim(app.UIAxes,[-150, 30])

            app.ratio = app.Ratio_Field.Value;
            app.angle_min = app.Angle_Min_field.Value;
            app.angle_max = app.Angle_Max_field.Value;
            app.margin = app.MargintobothsidesEditField.Value;
            app.L_or_R = app.Left_or_Right_dropdown.Value;
            app.dist_min = app.Min_Dist.Value;
            app.dist_max = app.Max_Dist.Value;
            app.plate_bone_dist = app.Plate_Bone_Dist.Value;
            app.parameters = struct();
            app.save_name = app.Save_Name.Value;
            app.max_in_bone = app.Max_In_Bone.Value;
        end

        % Button pushed function: LoadboneButton
        function Load_bone(app, event)
            % open verkenner en selecteer een mesh. Deze wordt dadelijk automatisch
            % geladen en geplot.
            [Bone, filepath_bone] = uigetfile('*.stl', 'Select a file');            

            % De if hieronder checkt of de gebruiker op Annuleren drukt.
            if isequal(Bone, 0)
                label = app.Label;
                label.Visible = 'on';
                label.Text = 'Cancelled';
            else
                % File selection was successful
                % disp(['Geselecteerd bestand: ', fullfile(filepath_bone, bone)]);
                label = app.Label;
                label.Visible = 'on';
                label.Text = sprintf('Bone selected');
                app.bonefileLabel.Text = fullfile(filepath_bone,Bone);
            
                % Als er een file is geselecteerd (filename ~= 0), wordt S de mesh
                % van die file. 

                app.bone = stlread(Bone);
                                
                axis(app.UIAxes,'equal');                

                app.bonemesh = trimesh(app.bone,'parent',app.UIAxes);
                
                app.bonemesh.FaceColor = [0.8902, 0.8549, 0.7882];
                app.bonemesh.EdgeColor = 'none';
                app.bonemesh.FaceLighting = 'gouraud';
                app.bonemesh.EdgeLighting = 'gouraud';
                app.bonemesh.AmbientStrength = 0.3;
                app.bonemesh.DiffuseStrength = 0.8;
                app.bonemesh.SpecularStrength = 0.5;
                app.bonemesh.SpecularExponent = 15;
                app.bonemesh.SpecularColorReflectance = 0.8;

                app.bonemeshtrans = trimesh(app.bone,'parent',app.UIAxes);

                app.bonemeshtrans.FaceColor = [0.7529, 0.7529, 0.7529];
                app.bonemeshtrans.EdgeColor = 'none';
                app.bonemeshtrans.FaceAlpha = 0.3;
                app.bonemeshtrans.EdgeAlpha = 0.3;
                app.bonemeshtrans.FaceLighting = 'gouraud';
                app.bonemeshtrans.EdgeLighting = 'gouraud';
                app.bonemeshtrans.AmbientStrength = 0.15;
                app.bonemeshtrans.DiffuseStrength = 1.0;
                app.bonemeshtrans.SpecularStrength = 0.5;
                app.bonemeshtrans.SpecularExponent = 5;
                app.bonemeshtrans.SpecularColorReflectance = 1;

                if app.bone_transparent == '1'
                    set(app.bonemeshtrans,'visible','on')
                    set(app.bonemesh,'visible','off')
                else
                    set(app.bonemesh,'visible','on')
                    set(app.bonemeshtrans,'visible','off')
                end
                
            end
        end

        % Button pushed function: LoadplateButton
        function Load_plate(app, event)
            % open verkenner en selecteer een mesh. Deze wordt dadelijk automatisch
            % geladen en geplot.
            [Plate, filepath_plate] = uigetfile('*.stl', 'Select a file');
            
            % De if hieronder checkt of de gebruiker op Annuleren drukt.
            if isequal(Plate, 0)
                label = app.Label;
                label.Visible = 'on';
                label.Text = 'Cancelled';
            else
                % File selection was successful
                label = app.Label;
                label.Visible = 'on';
                label.Text = sprintf('Plate selected');
                app.platefileLabel.Text = fullfile(filepath_plate,Plate);
            
                % Als er een file is geselecteerd (filename ~= 0), wordt S de mesh
                % van die file. 
                app.plate = stlread(Plate);
                
                axis(app.UIAxes,'equal')

                app.platemesh = trimesh(app.plate,'parent',app.UIAxes);

                app.platemesh.FaceColor = [0.7020, 0.5608, 0.1412]; %messing
                app.platemesh.EdgeColor = 'none';
                app.platemesh.FaceLighting = 'gouraud';
                app.platemesh.EdgeLighting = 'gouraud';
                app.platemesh.AmbientStrength = 0.15;
                app.platemesh.DiffuseStrength = 1.0;
                app.platemesh.SpecularStrength = 0.5;
                app.platemesh.SpecularExponent = 5;
                app.platemesh.SpecularColorReflectance = 1;
                
                app.platemeshtrans = trimesh(app.plate,'parent',app.UIAxes);

                app.platemeshtrans.FaceColor = [0.7020, 0.5608, 0.1412]; %messing
                app.platemeshtrans.EdgeColor = 'none';
                app.platemeshtrans.FaceAlpha = 0.3;
                app.platemeshtrans.EdgeAlpha = 0.3;
                app.platemeshtrans.FaceLighting = 'gouraud';
                app.platemeshtrans.EdgeLighting = 'gouraud';
                app.platemeshtrans.AmbientStrength = 0.15;
                app.platemeshtrans.DiffuseStrength = 1.0;
                app.platemeshtrans.SpecularStrength = 0.5;
                app.platemeshtrans.SpecularExponent = 5;
                app.platemeshtrans.SpecularColorReflectance = 1;
                
                if app.plate_transparent == 1
                    set(app.platemesh,'visible','off')
                    set(app.platemeshtrans,'visible','on')
                else
                    set(app.platemeshtrans,'visible','off')
                    set(app.platemesh,'visible','on')
                end
                
            end
        end

        % Button pushed function: RecalculateplatepositionButton
        function calculate(app, event)
            
            if app.L_or_R == "Select"
                app.Label.Text = "ERROR: Select left or right knee";
            elseif app.angle_max < app.angle_min
                app.Label.Text = "ERROR: angle max must be larger than angle min";
            else 
                app.Label.Text = "Calculating...";
                pause(1);
                clear app.plate_placed
                clear app.plate_placed_mirror
                if app.L_or_R == "Right"
                    [app.bone_mirror] = spiegelen(app.bone);
                    [app.surgicalwindow_mirror] = spiegelen(app.surgicalwindow);
    
                    [app.plate_placed_mirror] = plateplacement_copy2(app.bone_mirror,app.surgicalwindow_mirror,app.plate,app.ratio,app.angle_min,app.angle_max,app.margin,app.dist_min,app.dist_max,app.plate_bone_dist,app.max_in_bone);
                    if ~isempty(app.plate_placed_mirror)
                        [app.plate_placed] = spiegelen(app.plate_placed_mirror);
                    else
                        app.plate_placed = [];
                    end
                else
                    [app.plate_placed] = plateplacement_copy2(app.bone,app.surgicalwindow,app.plate,app.ratio,app.angle_min,app.angle_max,app.margin,app.dist_min,app.dist_max,app.plate_bone_dist,app.max_in_bone);
                end

                if ~isempty(app.plate_placed)
                
                    % app.plate_placed = stlread("TGO-stl\Patiënt 1\Q_plaat_1.stl");
                    app.platemesh_placed = trimesh(app.plate_placed,'parent',app.UIAxes);
        
                    app.platemesh_placed.FaceColor = [0.7020, 0.5608, 0.1412]; %messing
                    app.platemesh_placed.EdgeColor = 'none';
                    app.platemesh_placed.FaceLighting = 'gouraud';
                    app.platemesh_placed.EdgeLighting = 'gouraud';
                    app.platemesh_placed.AmbientStrength = 0.15;
                    app.platemesh_placed.DiffuseStrength = 1.0;
                    app.platemesh_placed.SpecularStrength = 0.5;
                    app.platemesh_placed.SpecularExponent = 5;
                    app.platemesh_placed.SpecularColorReflectance = 1;
                    
                    app.platemesh_placedtrans = trimesh(app.plate_placed,'parent',app.UIAxes);
        
                    app.platemesh_placedtrans.FaceColor = [0.7020, 0.5608, 0.1412]; %messing
                    app.platemesh_placedtrans.EdgeColor = 'none';
                    app.platemesh_placedtrans.FaceAlpha = 0.3;
                    app.platemesh_placedtrans.EdgeAlpha = 0.3;
                    app.platemesh_placedtrans.FaceLighting = 'gouraud';
                    app.platemesh_placedtrans.EdgeLighting = 'gouraud';
                    app.platemesh_placedtrans.AmbientStrength = 0.15;
                    app.platemesh_placedtrans.DiffuseStrength = 1.0;
                    app.platemesh_placedtrans.SpecularStrength = 0.5;
                    app.platemesh_placedtrans.SpecularExponent = 5;
                    app.platemesh_placedtrans.SpecularColorReflectance = 1;
                    
                    if app.plate_transparent == 1
                        set(app.platemesh,'visible','off')
                        set(app.platemeshtrans,'visible','off')
                        set(app.platemesh_placed,'visible','off')
                        set(app.platemesh_placedtrans,'visible','on')
                    else
                        set(app.platemesh,'visible','off')
                        set(app.platemeshtrans,'visible','off')
                        set(app.platemesh_placed,'visible','on')
                        set(app.platemesh_placedtrans,'visible','off')
                    end
        
                    % Parameters berekenen
                    
                    if app.L_or_R == "Right"
                        [dist_TP_plate,dist_plate_condyle,dist_plate_tuberositas,ratio_cond_mid,ratio_tub_mid,dist_in_bone,p_bonecontact,dist_top_wedge,dist_bottom_wedge,angle_TP_plate] = parameterweter(app.surgicalwindow_mirror,app.plate_placed_mirror,app.bone_mirror,app.P_wedge,app.plate_bone_dist,app.dist_min,(app.dist_max - app.dist_min));
                    else
                        [dist_TP_plate,dist_plate_condyle,dist_plate_tuberositas,ratio_cond_mid,ratio_tub_mid,dist_in_bone,p_bonecontact,dist_top_wedge,dist_bottom_wedge,angle_TP_plate] = parameterweter(app.surgicalwindow,app.plate_placed,app.bone,app.P_wedge,app.plate_bone_dist,app.dist_min,(app.dist_max - app.dist_min));
                    end
    
                    [rotation_m,translation_v] = findrottrans(app.plate,app.plate_placed);
    
                    app.Parameters.Text = ['Distance from tibia plateau to plate:', newline newline...
                            'Distance from plate to MCL:', newline newline...
                            'Distance from plate to tuberosity:', newline newline...
                            'Ratio MCL:plate:tuberosity:', newline newline...
                            'Maximum distance of plate in bone:', newline newline...
                            'Percentage of bonecontact:', newline newline...
                            'Distance from most distal proximal screwhole to wedge:', newline newline...
                            'Distance from most proximal distal screwhole to wedge:', newline newline...
                            'Angle between plate and tibia plateau:'];
    
                    app.Parameters_values.Text = [num2str(dist_TP_plate,'%.1f'), ' mm', newline newline...
                            num2str(dist_plate_condyle,'%.1f'), ' mm', newline newline...
                            num2str(dist_plate_tuberositas,'%.1f'), ' mm', newline newline...
                            num2str(ratio_cond_mid,'%.1f'), ' / ', num2str(ratio_tub_mid,'%.1f'), newline newline...
                            num2str(dist_in_bone,'%.2f'), ' mm', newline newline...
                            num2str(p_bonecontact,'%.1f'), char(37), newline newline...
                            num2str(dist_top_wedge,'%.1f'), ' mm', newline newline...
                            num2str(dist_bottom_wedge,'%.1f'), ' mm', newline newline...
                            num2str(angle_TP_plate,'%.1f'), char(176)];
    
                    app.parameters.dist_TP_plate = dist_TP_plate;
                    app.parameters.dist_plate_MCL = dist_plate_condyle;
                    app.parameters.dist_plate_tuberosity = dist_plate_tuberositas;
                    app.parameters.ratio_cond_mid = ratio_cond_mid;
                    app.parameters.ratio_tub_mid = ratio_tub_mid;
                    app.parameters.max_in_bone = dist_in_bone;
                    app.parameters.p_bonecontact = p_bonecontact;
                    app.parameters.dist_top_wedge = dist_top_wedge;
                    app.parameters.dist_bottom_wedge = dist_bottom_wedge;
                    app.parameters.angle_TP_plate = angle_TP_plate;
                    app.parameters.rotation_matrix = rotation_m;
                    app.parameters.translation_vector = translation_v;
    
                    app.Label.Text = 'Finished';
                else
                    app.Label.Text = 'ERROR: no plateposition has been found';
                end
            end
        end

        % Button pushed function: ExportplatepositionButton
        function export_c(app, event)
            savelocation = uigetdir();
                       
            if savelocation == 0
                label = app.Label;
                label.Visible = 'on';
                label.Text = 'Cancelled';
            else                
                plate_name = append(savelocation,'\',app.save_name,'.stl');

                stlwrite(app.plate_placed,plate_name);
                para_name = append(savelocation,'\',app.save_name,'_parameters');
                parameters_save = app.parameters;
                save(para_name,"parameters_save")
            end            
        end

        % Value changed function: Angle_Max_field
        function Angle_max(app, event)
            value = app.Angle_Max_field.Value;
            app.angle_max = value;
            
        end

        % Value changed function: Angle_Min_field
        function Angle_min(app, event)
            value = app.Angle_Min_field.Value;
            app.angle_min = value;
            
        end

        % Value changed function: Ratio_Field
        function ratio_field(app, event)
            value = app.Ratio_Field.Value;
            app.ratio = value;

            app.Ratio_100_minus_input.Text = ['/ ', num2str(100-value)];
        end

        % Value changed function: BonetransparentSwitch
        function bone_transparent_c(app, event)
            value = app.BonetransparentSwitch.Value;
            app.bone_transparent = value;
            if isempty(app.bone) ~= 1 && value == '1'
                set(app.bonemesh,'visible','off')
                set(app.bonemeshtrans,'visible','on')
            elseif isempty(app.bone) ~= 1 && value == '0'
                set(app.bonemesh,'visible','on')
                set(app.bonemeshtrans,'visible','off')
            end                
            
        end

        % Value changed function: PlatetransparentSwitch
        function plate_transparent_c(app, event)
            value = app.PlatetransparentSwitch.Value;
            app.plate_transparent = value;

            if ~isempty(app.platemesh) && value == 1 && isempty(app.plate_placed)
                set(app.platemesh,'visible','off')
                set(app.platemeshtrans,'visible','on')
            elseif ~isempty(app.platemesh) && value == 0 && isempty(app.plate_placed)
                set(app.platemesh,'visible','on')
                set(app.platemeshtrans,'visible','off')
            elseif ~isempty(app.platemesh) && value == 1 && ~isempty(app.plate_placed)
                set(app.platemesh_placedtrans,'visible','on')
                set(app.platemesh_placed,'visible','off')
            elseif ~isempty(app.platemesh) && value == 0 && ~isempty(app.plate_placed)
                set(app.platemesh_placedtrans,'visible','off')
                set(app.platemesh_placed,'visible','on')
            end 
            
        end

        % Value changed function: MargintobothsidesEditField
        function margin_c(app, event)
            value = app.MargintobothsidesEditField.Value;

            app.margin = value;            
        end

        % Button pushed function: LoadSWButton
        function load_sw(app, event)
            
            
            % De if hieronder checkt of de gebruiker op Annuleren drukt.
            if app.L_or_R == "Select"
                app.Label.Text = "ERROR: Select left or right knee";
            elseif app.L_or_R == "Right"
                [SW, filepath_sw] = uigetfile('*.stl', 'Select a file');
                if isequal(SW, 0)
                    label = app.Label;
                    label.Visible = 'on';
                    label.Text = 'Cancelled';
                else
                    % File selection was successful
                    % disp(['Geselecteerd bestand: ', fullfile(filepath_bone, bone)]);
                    label = app.Label;
                    label.Visible = 'on';
                    label.Text = sprintf('Surgical window selected');
                    app.SWlabel.Text = fullfile(filepath_sw,SW);
    
                    app.surgicalwindow = stlread(SW);
                    app.surgicalwindow_mirror = spiegelen(app.surgicalwindow);
                                    
                    % Plot SW
                    % 
                    % 
                    % app.SWmesh = trimesh(app.surgicalwindow,'parent',app.UIAxes);
                    % hold(app.UIAxes, 'on');
                    % 
                    % app.SWmesh.FaceColor = [0.8902, 0.8549, 0.7882];
                    % app.SWmesh.EdgeColor = 'none';
                    % app.SWmesh.FaceLighting = 'gouraud';
                    % app.SWmesh.EdgeLighting = 'gouraud';
                    % app.SWmesh.AmbientStrength = 0.3;
                    % app.SWmesh.DiffuseStrength = 0.8;
                    % app.SWmesh.SpecularStrength = 0.5;
                    % app.SWmesh.SpecularExponent = 15;
                    % app.SWmesh.SpecularColorReflectance = 0.8;
                    % 
                    % app.SWmeshtrans = trimesh(app.surgicalwindow,'parent',app.UIAxes);
                    % 
                    % app.SWmeshtrans.FaceColor = [0.7529, 0.7529, 0.7529];
                    % app.SWmeshtrans.EdgeColor = 'none';
                    % app.SWmeshtrans.FaceAlpha = 0.3;
                    % app.SWmeshtrans.EdgeAlpha = 0.3;
                    % app.SWmeshtrans.FaceLighting = 'gouraud';
                    % app.SWmeshtrans.EdgeLighting = 'gouraud';
                    % app.SWmeshtrans.AmbientStrength = 0.15;
                    % app.SWmeshtrans.DiffuseStrength = 1.0;
                    % app.SWmeshtrans.SpecularStrength = 0.5;
                    % app.SWmeshtrans.SpecularExponent = 5;
                    % app.SWmeshtrans.SpecularColorReflectance = 1;
                    % 
                    % set(app.SWmesh,'visible','off')
                    
                    [app.Points_wedge,app.P_wedge] = click4wedge_segmenteren(app.surgicalwindow_mirror);
                end
            else
                [SW, filepath_sw] = uigetfile('*.stl', 'Select a file');
                if isequal(SW, 0)
                    label = app.Label;
                    label.Visible = 'on';
                    label.Text = 'Cancelled';
                else
                    % File selection was successful
                    % disp(['Geselecteerd bestand: ', fullfile(filepath_bone, bone)]);
                    label = app.Label;
                    label.Visible = 'on';
                    label.Text = sprintf('Surgical window selected');
                    app.SWlabel.Text = fullfile(filepath_sw,SW);
    
                    app.surgicalwindow = stlread(SW);
                                                        
                    % Plot SW
                    % 
                    % 
                    % app.SWmesh = trimesh(app.surgicalwindow,'parent',app.UIAxes);
                    % hold(app.UIAxes, 'on');
                    % 
                    % app.SWmesh.FaceColor = [0.8902, 0.8549, 0.7882];
                    % app.SWmesh.EdgeColor = 'none';
                    % app.SWmesh.FaceLighting = 'gouraud';
                    % app.SWmesh.EdgeLighting = 'gouraud';
                    % app.SWmesh.AmbientStrength = 0.3;
                    % app.SWmesh.DiffuseStrength = 0.8;
                    % app.SWmesh.SpecularStrength = 0.5;
                    % app.SWmesh.SpecularExponent = 15;
                    % app.SWmesh.SpecularColorReflectance = 0.8;
                    % 
                    % app.SWmeshtrans = trimesh(app.surgicalwindow,'parent',app.UIAxes);
                    % 
                    % app.SWmeshtrans.FaceColor = [0.7529, 0.7529, 0.7529];
                    % app.SWmeshtrans.EdgeColor = 'none';
                    % app.SWmeshtrans.FaceAlpha = 0.3;
                    % app.SWmeshtrans.EdgeAlpha = 0.3;
                    % app.SWmeshtrans.FaceLighting = 'gouraud';
                    % app.SWmeshtrans.EdgeLighting = 'gouraud';
                    % app.SWmeshtrans.AmbientStrength = 0.15;
                    % app.SWmeshtrans.DiffuseStrength = 1.0;
                    % app.SWmeshtrans.SpecularStrength = 0.5;
                    % app.SWmeshtrans.SpecularExponent = 5;
                    % app.SWmeshtrans.SpecularColorReflectance = 1;
                    % 
                    % set(app.SWmesh,'visible','off')
                    
                    [app.Points_wedge,app.P_wedge] = click4wedge_segmenteren(app.surgicalwindow);
                end
                
            end
        end

        % Value changed function: Left_or_Right_dropdown
        function leftorright(app, event)
            value = app.Left_or_Right_dropdown.Value;
            app.L_or_R = value;
            
        end

        % Value changed function: Min_Dist
        function dist_min_c(app, event)
            value = app.Min_Dist.Value;

            app.dist_min = value;            
        end

        % Value changed function: Max_Dist
        function dist_max_c(app, event)
            value = app.Max_Dist.Value;

            app.dist_max = value;            
        end

        % Value changed function: Plate_Bone_Dist
        function plate_bone_dist_c(app, event)
            value = app.Plate_Bone_Dist.Value;

            app.plate_bone_dist = value;            
        end

        % Value changed function: Save_Name
        function save_name_c(app, event)
            value = app.Save_Name.Value;
            app.save_name = value;
        end

        % Button pushed function: Reset_parameters
        function reset(app, event)
            app.dist_min = 5;
            app.Min_Dist.Value = 5;

            app.dist_max = 10;
            app.Max_Dist.Value = 10;

            app.ratio = 45;
            app.Ratio_Field.Value = 45;

            app.margin = 5;
            app.MargintobothsidesEditField.Value = 5;

            app.angle_min = 5;
            app.Angle_Min_field.Value = 5;

            app.angle_max = 10;
            app.Angle_Max_field.Value = 10;
            
            app.max_in_bone = 1;
            app.Max_In_Bone.Value = 1;

            app.plate_bone_dist = 0.2;
            app.Plate_Bone_Dist.Value = 0.2;
        end

        % Value changed function: Max_In_Bone
        function Max_in_bone_c(app, event)
            value = app.Max_In_Bone.Value;
            app.max_in_bone = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Bone and plate')
            xlabel(app.UIAxes, 'X (medial - lateral)')
            ylabel(app.UIAxes, 'Y (anterior - posterior)')
            zlabel(app.UIAxes, 'Z (proximal - distal)')
            app.LoadboneButton = uibutton(app.GridLayout, 'push');
            app.LoadboneButton.ButtonPushedFcn = createCallbackFcn(app, @Load_bone, true);
            app.LoadboneButton.Text = 'Load bone';
            app.LoadplateButton = uibutton(app.GridLayout, 'push');
            app.LoadplateButton.ButtonPushedFcn = createCallbackFcn(app, @Load_plate, true);
            app.LoadplateButton.Text = 'Load plate';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Load models...';
            app.RatioofthelocationoftheplatebetweentheMCLandtuberosityLabel = uilabel(app.GridLayout);
            app.RatioofthelocationoftheplatebetweentheMCLandtuberosityLabel.Text = 'Ratio of the location of the plate between the MCL and tuberosity (%):';
            app.RecalculateplatepositionButton = uibutton(app.GridLayout, 'push');
            app.RecalculateplatepositionButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.RecalculateplatepositionButton.Text = '(Re)calculate plateposition';
            app.ExportplatepositionButton = uibutton(app.GridLayout, 'push');
            app.ExportplatepositionButton.ButtonPushedFcn = createCallbackFcn(app, @export_c, true);
            app.ExportplatepositionButton.Text = 'Export plate position';
            app.bonefileLabel = uilabel(app.GridLayout);
            app.bonefileLabel.Text = 'Load post-op tibia (only top half of bone is preffered for speed)';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Angle min (°) :';
            app.Angle_Min_field = uieditfield(app.GridLayout, 'numeric');
            app.Angle_Min_field.ValueChangedFcn = createCallbackFcn(app, @Angle_min, true);
            app.Angle_Min_field.Value = 5;
            app.Angle_Max_field = uieditfield(app.GridLayout, 'numeric');
            app.Angle_Max_field.ValueChangedFcn = createCallbackFcn(app, @Angle_max, true);
            app.Angle_Max_field.Value = 10;
            app.AnglemaxLabel = uilabel(app.GridLayout);
            app.AnglemaxLabel.Text = 'Angle max (°):';
            app.Ratio_Field = uieditfield(app.GridLayout, 'numeric');
            app.Ratio_Field.ValueChangedFcn = createCallbackFcn(app, @ratio_field, true);
            app.Ratio_Field.Value = 45;
            app.MaximaldistanceundertibiaplateaummLabel = uilabel(app.GridLayout);
            app.MaximaldistanceundertibiaplateaummLabel.Text = 'Maximal distance under tibia plateau (mm):';
            app.Max_Dist = uieditfield(app.GridLayout, 'numeric');
            app.Max_Dist.ValueChangedFcn = createCallbackFcn(app, @dist_max_c, true);
            app.Max_Dist.Value = 10;
            app.Ratio_100_minus_input = uilabel(app.GridLayout);
            app.Ratio_100_minus_input.Text = '/ 55';
            app.MargintobothsidesLabel = uilabel(app.GridLayout);
            app.MargintobothsidesLabel.Text = 'Margin to both sides (%):';
            app.MargintobothsidesEditField = uieditfield(app.GridLayout, 'numeric');
            app.MargintobothsidesEditField.ValueChangedFcn = createCallbackFcn(app, @margin_c, true);
            app.MargintobothsidesEditField.Value = 5;
            app.platefileLabel = uilabel(app.GridLayout);
            app.platefileLabel.Text = 'Load platemodel';
            app.BonetransparentSwitchLabel = uilabel(app.GridLayout);
            app.BonetransparentSwitchLabel.Text = 'Bone transparent';
            app.BonetransparentSwitch = uiswitch(app.GridLayout, 'slider');
            app.BonetransparentSwitch.ItemsData = {'0', '1'};
            app.BonetransparentSwitch.ValueChangedFcn = createCallbackFcn(app, @bone_transparent_c, true);
            app.BonetransparentSwitch.Value = '0';
            app.PlatetransparentSwitchLabel = uilabel(app.GridLayout);
            app.PlatetransparentSwitchLabel.Text = 'Plate transparent';
            app.PlatetransparentSwitch = uiswitch(app.GridLayout, 'slider');
            app.PlatetransparentSwitch.ItemsData = [0 1];
            app.PlatetransparentSwitch.ValueChangedFcn = createCallbackFcn(app, @plate_transparent_c, true);
            app.PlatetransparentSwitch.Value = 0;
            app.LoadSWButton = uibutton(app.GridLayout, 'push');
            app.LoadSWButton.ButtonPushedFcn = createCallbackFcn(app, @load_sw, true);
            app.LoadSWButton.Text = 'Load surgical window';
            app.SWlabel = uilabel(app.GridLayout);
            app.SWlabel.Text = 'Load cut out surgical window';
            app.LeftorrightkneeLabel = uilabel(app.GridLayout);
            app.LeftorrightkneeLabel.Text = 'Left or right knee:';
            app.Left_or_Right_dropdown = uidropdown(app.GridLayout);
            app.Left_or_Right_dropdown.Items = {'Select', 'Left', 'Right'};
            app.Left_or_Right_dropdown.ValueChangedFcn = createCallbackFcn(app, @leftorright, true);
            app.Left_or_Right_dropdown.Value = 'Select';
            app.FordetailedexplanationandinstructionsseedocumentationLabel = uilabel(app.GridLayout);
            app.FordetailedexplanationandinstructionsseedocumentationLabel.Text = 'For detailed explanation and instructions: see documentation';
            app.MinimaldistanceundertibiaplateaummEditFieldLabel = uilabel(app.GridLayout);
            app.MinimaldistanceundertibiaplateaummEditFieldLabel.Text = 'Minimal distance under tibia plateau (mm):';
            app.Min_Dist = uieditfield(app.GridLayout, 'numeric');
            app.Min_Dist.ValueChangedFcn = createCallbackFcn(app, @dist_min_c, true);
            app.Min_Dist.Value = 5;
            app.DistanceplatebonetocountassurfacecontactmmLabel = uilabel(app.GridLayout);
            app.DistanceplatebonetocountassurfacecontactmmLabel.Text = 'Distance plate-bone to count as surface contact (mm):';
            app.Plate_Bone_Dist = uieditfield(app.GridLayout, 'numeric');
            app.Plate_Bone_Dist.ValueChangedFcn = createCallbackFcn(app, @plate_bone_dist_c, true);
            app.Plate_Bone_Dist.Value = 0.2;
            app.Parameters = uilabel(app.GridLayout);
            app.Parameters.Text = '';
            app.Save_Name = uieditfield(app.GridLayout, 'text');
            app.Save_Name.CharacterLimits = [1 Inf];
            app.Save_Name.ValueChangedFcn = createCallbackFcn(app, @save_name_c, true);
            app.Save_Name.Value = 'plateposition';
            app.stlLabel = uilabel(app.GridLayout);
            app.stlLabel.Text = '.stl';
            app.Reset_parameters = uibutton(app.GridLayout, 'push');
            app.Reset_parameters.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.Reset_parameters.Text = 'Reset parameters';
            app.Parameters_values = uilabel(app.GridLayout);
            app.Parameters_values.Text = '';
            app.ParametersofplacedplateLabel = uilabel(app.GridLayout);
            app.ParametersofplacedplateLabel.Text = 'Parameters of placed plate ';
            app.MaximumdistanceinbonemmEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumdistanceinbonemmEditFieldLabel.Text = 'Maximum distance in bone (mm):';
            app.Max_In_Bone = uieditfield(app.GridLayout, 'numeric');
            app.Max_In_Bone.ValueChangedFcn = createCallbackFcn(app, @Max_in_bone_c, true);
            app.Max_In_Bone.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end